classdef Patterns < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ShowimageButton               matlab.ui.control.Button
        plot_button                   matlab.ui.control.Button
        samp_freq                     matlab.ui.control.EditField
        SamplingfrequencyEditFieldLabel  matlab.ui.control.Label
        freq                          matlab.ui.control.EditField
        FrequencyEditFieldLabel       matlab.ui.control.Label
        num_cycles                    matlab.ui.control.EditField
        NumberofcyclesEditFieldLabel  matlab.ui.control.Label
        graph                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: plot_button
        function plot_buttonPushed(app, event)
            % if isnumeric(app.num_cycles) &&  isnumeric(app.freq) && isnumeric(app.samp_freq)
                
                f = str2double(app.freq.Value);
                num_cyc = str2double(app.num_cycles.Value);
                samp = str2double(app.samp_freq.Value);


                End_Time = num_cyc*1/f;
                Start_Time = 0;
                Sampling_Period = 1/samp;

                t = (Start_Time: Sampling_Period: End_Time);
                y = sin(2*pi*f*t);                               
                plot(app.graph, t, y, '-.');

           
        end

        % Button pushed function: ShowimageButton
        function ShowimageButtonPushed(app, event)

            f = str2double(app.freq.Value);
            num_cyc = str2double(app.num_cycles.Value);
            samp = str2double(app.samp_freq.Value);

            End_Time = num_cyc*1/f;
            Start_Time = 0;
            Sampling_Period = 1/samp;

            t = (Start_Time: Sampling_Period: End_Time);
            y = sin(2*pi*f*t);  

            Image_Height = 2160;
            im1 = repmat(y,Image_Height,1);
            im1 = imresize(im1, [2160 3840]);            
            % imshow(im1, 'Parent', app.image_backup);

            figure
            imshow(im1)
            impixelinfo(im1)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.graph = uiaxes(app.UIFigure);
            title(app.graph, 'Pattern')
            xlabel(app.graph, 'Time')
            ylabel(app.graph, 'Amplitude')
            zlabel(app.graph, 'Z')
            app.NumberofcyclesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofcyclesEditFieldLabel.Text = 'Number of cycles';
            app.num_cycles = uieditfield(app.UIFigure, 'text');
            app.num_cycles.Value = '20';
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.freq = uieditfield(app.UIFigure, 'text');
            app.freq.Value = '1';
            app.SamplingfrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingfrequencyEditFieldLabel.Text = 'Sampling frequency';
            app.samp_freq = uieditfield(app.UIFigure, 'text');
            app.samp_freq.Value = '100';
            app.plot_button = uibutton(app.UIFigure, 'push');
            app.plot_button.ButtonPushedFcn = createCallbackFcn(app, @plot_buttonPushed, true);
            app.plot_button.Text = 'Plot';
            app.ShowimageButton = uibutton(app.UIFigure, 'push');
            app.ShowimageButton.ButtonPushedFcn = createCallbackFcn(app, @ShowimageButtonPushed, true);
            app.ShowimageButton.Text = 'Show image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Patterns

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end